classdef tubes3app_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        TabGroup                matlab.ui.container.TabGroup
        Nomor1Tab               matlab.ui.container.Tab
        GridLayout2             matlab.ui.container.GridLayout
        GridLayout4             matlab.ui.container.GridLayout
        GridLayout7             matlab.ui.container.GridLayout
        OperatorDropDown        matlab.ui.control.DropDown
        OperatorDropDownLabel   matlab.ui.control.Label
        GridLayout6             matlab.ui.container.GridLayout
        ProcessButton           matlab.ui.control.Button
        InputButton             matlab.ui.control.Button
        GridLayout3             matlab.ui.container.GridLayout
        SegmentationImageLabel  matlab.ui.control.Label
        EdgeImageLabel          matlab.ui.control.Label
        OriginalImageLabel      matlab.ui.control.Label
        Image4                  matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        Nomor2Tab               matlab.ui.container.Tab
        GridLayout8             matlab.ui.container.GridLayout
        GridLayout10            matlab.ui.container.GridLayout
        Image6                  matlab.ui.control.Image
        Image5                  matlab.ui.control.Image
        GridLayout9             matlab.ui.container.GridLayout
        ProcessButton_2         matlab.ui.control.Button
        InputButton_2           matlab.ui.control.Button
    end

    
    properties (Access = private)
        inputImage0 % Description
        inputImage1
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputButton
        function InputButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');  %open a mat file
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                % Construct the full file path
                image = imread(fullfile(path, file));

                if size(image, 3) ~= 3
                    image = cat(3, image, image, image);
                end
                app.inputImage0 = image;
                
                % Display the image in the app's UI Image component (app.Image)
                app.Image.ImageSource = app.inputImage0;
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            img = app.inputImage0;
            
            operator = app.OperatorDropDown.Value;
            switch operator
                case 'Laplace'
                    image_edge = laplaceDetection(img);
                case 'LoG'
                    image_edge = logDetection(img);
                case 'Sobel'
                    image_edge = sobelDetection(img);
                case 'Prewitt'
                    image_edge = prewittDetection(img);
                case 'Roberts'
                    image_edge = robertsDetection(img);
                case 'Canny'
                    image_edge = cannyDetection(img);
            end

            app.Image2.ImageSource = cat(3, image_edge, image_edge, image_edge);

            image_segmentation = objectSegmentation(img, image_edge);

            app.Image4.ImageSource = image_segmentation;
        end

        % Button pushed function: InputButton_2
        function InputButton_2Pushed(app, event)
            [file,path] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp');  %open a mat file
            if isequal(file, 0)
                disp('User canceled file selection');
            else
                % Construct the full file path
                image = imread(fullfile(path, file));

                if size(image, 3) ~= 3
                    image = cat(3, image, image, image);
                end
                app.inputImage1 = image;
                
                % Display the image in the app's UI Image component (app.Image)
                app.Image5.ImageSource = app.inputImage1;
            end
        end

        % Button pushed function: ProcessButton_2
        function ProcessButton_2Pushed(app, event)
            app.Image6.ImageSource = lineDetection(app.inputImage1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Nomor1Tab = uitab(app.TabGroup);
            app.Nomor1Tab.Title = 'Nomor 1';
            app.GridLayout2 = uigridlayout(app.Nomor1Tab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Image = uiimage(app.GridLayout3);
            app.Image2 = uiimage(app.GridLayout3);
            app.Image4 = uiimage(app.GridLayout3);
            app.OriginalImageLabel = uilabel(app.GridLayout3);
            app.OriginalImageLabel.Text = 'Original Image';
            app.EdgeImageLabel = uilabel(app.GridLayout3);
            app.EdgeImageLabel.Text = 'Edge Image';
            app.SegmentationImageLabel = uilabel(app.GridLayout3);
            app.SegmentationImageLabel.Text = 'Segmentation Image';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.InputButton = uibutton(app.GridLayout6, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.InputButton.Text = 'Input';
            app.ProcessButton = uibutton(app.GridLayout6, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.OperatorDropDownLabel = uilabel(app.GridLayout7);
            app.OperatorDropDownLabel.Text = 'Operator';
            app.OperatorDropDown = uidropdown(app.GridLayout7);
            app.OperatorDropDown.Items = {'Laplace', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.OperatorDropDown.Value = 'Laplace';
            app.Nomor2Tab = uitab(app.TabGroup);
            app.Nomor2Tab.Title = 'Nomor 2';
            app.GridLayout8 = uigridlayout(app.Nomor2Tab);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.InputButton_2 = uibutton(app.GridLayout9, 'push');
            app.InputButton_2.ButtonPushedFcn = createCallbackFcn(app, @InputButton_2Pushed, true);
            app.InputButton_2.Text = 'Input';
            app.ProcessButton_2 = uibutton(app.GridLayout9, 'push');
            app.ProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_2Pushed, true);
            app.ProcessButton_2.Text = 'Process';
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.Image5 = uiimage(app.GridLayout10);
            app.Image6 = uiimage(app.GridLayout10);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tubes3app_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end